classdef parachuteApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        DiameterSlider                  matlab.ui.control.Slider
        DiameterEditFieldLabel          matlab.ui.control.Label
        Diameter                        matlab.ui.control.NumericEditField
        SHDslider                       matlab.ui.control.Slider
        SpillHoleDiameterLabel          matlab.ui.control.Label
        SHD                             matlab.ui.control.NumericEditField
        ofGoresSpinnerLabel             matlab.ui.control.Label
        NOG                             matlab.ui.control.Spinner
        diameterUnits                   matlab.ui.control.Label
        spillholeUnits                  matlab.ui.control.Label
        SeamWidthDropDownLabel          matlab.ui.control.Label
        SeamWidthDropDown               matlab.ui.control.DropDown
        ParachuteTypeButtonGroup        matlab.ui.container.ButtonGroup
        FlatCircularButton              matlab.ui.control.RadioButton
        HemisphericalButton             matlab.ui.control.RadioButton
        TorridialButton                 matlab.ui.control.RadioButton
        CD10Label                       matlab.ui.control.Label
        CD20Label                       matlab.ui.control.Label
        CD08Label                       matlab.ui.control.Label
        CDLabel                         matlab.ui.control.Label
        CDEditField                     matlab.ui.control.NumericEditField
        Switch                          matlab.ui.control.Switch
        OverrideCDLabel                 matlab.ui.control.Label
        DescentVelocityCalculatorPanel  matlab.ui.container.Panel
        RocketMassEditFieldLabel        matlab.ui.control.Label
        RocketMassEditField             matlab.ui.control.NumericEditField
        Lamp                            matlab.ui.control.Lamp
        DescentVelocityEditFieldLabel   matlab.ui.control.Label
        DescentVelocityEditField        matlab.ui.control.NumericEditField
        MassUnits                       matlab.ui.control.Label
        velocityUnits                   matlab.ui.control.Label
        ParachuteDescentVelocityandGoreCalculatorLabel  matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        Label                           matlab.ui.control.Label
        ExportasDXFfileexportsdashedblueseamlinesPanel  matlab.ui.container.Panel
        DXFFileNameEditFieldLabel       matlab.ui.control.Label
        DXFFileNameEditField            matlab.ui.control.EditField
        ExportButton                    matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        d % parachute diameter
        n % number of gores
        R % parachute radius
        sD %spillhole diameter
        sR %spillhole radius
        iR %inner radius
        rM %mid radius
        rMM %coordinate to mid point of donut
        iD %inner Diameter
        sA % surfaceArea
        upTo %arclength where radius of spillhole is equal
        w %width function
        wP %positive line
        wN %negative line
        wPs %positive line for seam
        wNs %negative line for seam
        cD %coefficient of Drag
        m %rocket Mass
        p %air density
        G %gravity
        seam %seam width
        zero % plot up to for seam width
        zeroT % special for torus because plotted backwards
    end
    
    methods (Access = private)
        
        function plotGore(app)
            
            type = getParachuteType(app);
            cla(app.UIAxes) %clears graph each time
         
            switch type(1)
                case 1
                    hold(app.UIAxes,"on")
                    
                    %width lines
                    fplot(app.UIAxes,app.wP, [0 app.upTo], 'r');
                    fplot(app.UIAxes,app.wN, [0 app.upTo],'r');
                    
                    %vertical lines
                    plot(app.UIAxes, [0,0], [app.wP(0), app.wN(0)], 'r'); 
                    plot(app.UIAxes, [app.upTo,app.upTo], [app.wP(app.upTo), app.wN(app.upTo)],'r');
                    
                    %seam allowance
                    fplot(app.UIAxes, app.wPs,[-app.seam,app.zero], '--b');
                    fplot(app.UIAxes, app.wNs, [-app.seam,app.zero], '--b');
                    
                    %vertical lines for seam
                    plot(app.UIAxes, [-app.seam,-app.seam], [app.wPs(-app.seam), app.wNs(-app.seam)], '--b');
                    plot(app.UIAxes, [app.zero,app.zero], [app.wPs(app.zero), app.wNs(app.zero)], '--b');
                   
                    axis(app.UIAxes, [-5 80 -30 30]);
                    %title(app.UIAxes,'Parachute Gore'); 
                case 2
                    hold(app.UIAxes,"on")
                    
                    %width lines
                    fplot(app.UIAxes,app.wP, [0, app.upTo], 'r');
                    fplot(app.UIAxes,app.wN, [0, app.upTo],'r');
                    
                    %vertical lines
                    plot(app.UIAxes, [0,0], [app.wP(0), app.wN(0)], 'r');
                    plot(app.UIAxes, [app.upTo,app.upTo], [app.wP(app.upTo), app.wN(app.upTo)],'r');
                    
                    %seam allowance
                    fplot(app.UIAxes, app.wPs,[-app.seam,app.zero], '--b');
                    fplot(app.UIAxes, app.wNs, [-app.seam,app.zero], '--b');
                    
                    %vertical lines for seam
                    plot(app.UIAxes, [-app.seam,-app.seam], [app.wPs(-app.seam), app.wNs(-app.seam)], '--b');
                    plot(app.UIAxes, [app.zero,app.zero], [app.wPs(app.zero), app.wNs(app.zero)], '--b');
                   
                    axis(app.UIAxes, [-5 100 -30 30]);
                   % title(app.UIAxes,'Parachute Gore');   
                case 3
                    hold(app.UIAxes,"on")
                    
                    %width lines 
                    fplot(app.UIAxes,app.wP, [0, app.upTo], 'r');
                    fplot(app.UIAxes,app.wN, [0, app.upTo],'r');
                    
                    %vertical lines
                    plot(app.UIAxes, [0,0], [app.wP(0), app.wN(0)], 'r');
                    plot(app.UIAxes, [app.upTo,app.upTo], [app.wP(app.upTo), app.wN(app.upTo)],'r');
                    
                    %seam allowance
                    fplot(app.UIAxes, app.wPs, [-app.seam, app.zero], '--b'); %does not have zero
                    fplot(app.UIAxes, app.wNs, [-app.seam, app.zero], '--b');
                    
                    %vertical lines for seam
                    plot(app.UIAxes, [-app.seam,-app.seam], [app.wPs(-app.seam), app.wNs(-app.seam)], '--b');
                    plot(app.UIAxes, [app.zero,app.zero], [app.wPs(app.zero), app.wNs(app.zero)], '--b');
                    
                    axis(app.UIAxes, [-5 100 -30 30]);
                   % title(app.UIAxes,'Parachute Gore');   
            end
           
        end
        
        function getGore(app)

            t = 2 * pi / app.n; %theta for each gore
            
            type = getParachuteType(app);
            
            %app.Label.Text = num2str(app.R);
           
            switch type(1)
                case 1 %width functions for Flat Circular
                    app.w = @(r) (app.R - r) * t;
                    app.wP = @(r) (app.R - r) * t / 2;
                    app.wN = @(r) (r - app.R)* t / 2;
                    
                    %seam functions
                    app.wPs = @(r) app.wP(r) + app.seam; 
                    app.wNs = @(r) app.wN(r) - app.seam;
                    
                    %for spillhole
                    sMax = app.R - app.sR;          
                    app.upTo = sMax;
                    
                    %for seam width
                    if app.sR == 0
                        app.zero = fzero(app.wPs, sMax);
                    else
                        app.zero = sMax + app.seam;
                    end
                    
%                        
                case 2 %width functions for Hemispherical
                    app.w = @(s) app.R * cos(s/app.R) * t;
                    app.wP = @(s) (app.R * cos(s/app.R) * t)/2;
                    app.wN = @(s) -(app.R * cos(s/app.R) * t)/2;
                    
                    %seam functions
                    app.wPs = @(r) app.wP(r) + app.seam; 
                    app.wNs = @(r) app.wN(r) - app.seam;
                    
                    %for spill hole
                    sMax = app.R * pi/2;
                    app.upTo = sMax;
                        if app.sD ~= 0  %if there is spill hole input
                            app.upTo = calcSpillHole(app);
                        end
                    
                    %for seam width
                    if app.sR == 0
                        app.zero = fzero(app.wPs, sMax);
                    else
                        app.zero = app.upTo + app.seam;
                    end
                    
                case 3
                    app.rM = (app.R - app.iR)/2;
                    app.rMM = (app.iR + app.rM);
                    
                    app.w = @(s) (app.rMM - app.rM * cos(s/app.rM)) * t;
                    app.wP = @(s) (app.rMM - app.rM * cos(s/app.rM)) * t / 2;
                    app.wN = @(s) -(app.rMM - app.rM * cos(s/app.rM)) * t / 2;
                    
                    %seam functions
                    app.wPs = @(r) app.wP(r) + app.seam; 
                    app.wNs = @(r) app.wN(r) - app.seam;
                    
                    sMax = app.rM * pi;
                    app.upTo = sMax;
                                   
                    app.zero = app.upTo + app.seam;
                
            end 
        end
        
        function s = calcSpillHole(app)
            %app.sR = spillDiam / 2;
            s = app.R * acos(app.sR/app.R); %returns arclength where radius is equal
        end
        
        
        function surfaceArea = calcSurfArea(app)
            surfaceArea = app.n * (integral(app.w, 0, app.upTo)); %2 x for negative portion
            surfaceArea = surfaceArea / 144; %converts into ft^2
        
            app.Label.Text = ['Surface Area: ', num2str(surfaceArea), ' ft^2'];
        end
        
        function descVel = calcDescVal(app)
                app.sA = calcSurfArea(app); %calculates surface area
                
                descVel = sqrt((2 * app.m * app.G)/(app.cD * app.sA * app.p));  
                
                  if descVel <= 20
                        app.Lamp.Color = 'g';
                  elseif descVel <= 25
                        app.Lamp.Color = [1.00,1.00,0.00];
                    else
                        app.Lamp.Color = 'r';
                  end   
        end
        
        function type = getParachuteType(app)
           if app.FlatCircularButton.Value 
               type = [1 .8];
           elseif app.HemisphericalButton.Value
               type = [2 1.0];
           else
               type = [3 2.0];
           end
        end
        
        
        function lb = kgtolb(~, kg)
            lb = kg * 2.20462;
        end
        
        function ft = mtoft(~, m)
            ft = m * 3.28084;
        end
        
        function m = fttom(~, ft)
            m = ft / 3.28084;
        end
        
        function kg = lbtokg(~, lb)
            kg = lb /  2.20462;
        end
        
        function plot3D(app)
         
            type = getParachuteType(app);
            switch type(1)
                case 1 %Flat Circular (cylindrical)
                    theta = linspace(0,2*pi, app.n+1);
                    r = linspace(app.sR,app.R, 10);
                    [theta,r] = meshgrid(theta,r);
                    z = -r/50;
                    [X,Y,Z] = pol2cart(theta, r, z);
                    
                    cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    %colormap(app.UIAxes_2,"hsv");
                    axis(app.UIAxes_2, [-50 50 -50 50 -5 1]);
                    
                case 2 %Hemispherical (spherical)
                    phiMax = acos(app.sR/app.R);
                    phi = linspace(0,phiMax, 10); %app.upto was phimax
                    theta = linspace(0, 2*pi, (app.n + 1));
                    [theta, phi] = meshgrid(theta,phi);
                    rho = app.R;
                    [X,Y,Z] = sph2cart(theta, phi, rho);
                    
                    cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    %colormap(app.UIAxes_2, "hsv");
                    axis(app.UIAxes_2, [-50 50 -50 50 0 50]);
                case 3
                    theta = linspace(0,2*pi, app.n+1);
                    r = linspace((app.rMM - app.rM),(app.rMM + app.rM), 10);
                    [theta,r] = meshgrid(theta,r);
                    z = sqrt(app.rM.^2 - (r - app.rMM).^2);
                    [X,Y,Z] = pol2cart(theta, r, z);
                    
                    cla(app.UIAxes_2)
                    surf(app.UIAxes_2,X,Y,Z);
                    %colormap(app.UIAxes_2, "hsv");
                    axis(app.UIAxes_2, [-50 50 -50 50 0 50]);
            end


        end
        
        function change(app)
                getGore(app);
                plotGore(app);
                plot3D(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CDEditField.Visible = 'off';
            app.cD = .8;
            app.p = .0765;
            app.G = 32.1741;
            app.R = 0;
            app.sR = 0;
            app.iR = 0;
            app.n = 0;
            app.m = 0;
            app.seam = 1/4;
        end

        % Value changed function: Diameter
        function DiameterValueChanged(app, event)
            
            app.DiameterSlider.Value = app.Diameter.Value;   
            app.d = app.Diameter.Value;
            app.R = app.Diameter.Value/2;
            change(app);
            if app.NOG.Value ~= 0 
               change(app);
            end
            
            if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: DiameterSlider
        function DiameterSliderValueChanged(app, event)
            app.Diameter.Value = app.DiameterSlider.Value;
            app.d = app.DiameterSlider.Value;
            app.R = app.Diameter.Value/2;
            
            if app.NOG.Value ~= 0 
                change(app);
            end
            
            if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changing function: DiameterSlider
        function DiameterSliderValueChanging(app, event)
            app.Diameter.Value = event.Value;
            app.d = event.Value;
            app.R = app.Diameter.Value/2;
           if app.NOG.Value ~= 0 
                 change(app);
           end
           
           if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: NOG
        function NOGValueChanged(app, event)
            app.NOG.Value = event.Value;
            app.n = event.Value;
            
            if app.Diameter.Value ~= 0 
                 change(app);
            end
        end

        % Value changing function: NOG
        function NOGValueChanging(app, event)
            app.NOG.Value = event.Value;
            app.n = event.Value;
            
            if app.Diameter.Value ~= 0 
                  change(app);
            end
        end

        % Value changed function: SHD
        function SHDValueChanged(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHDslider.Value = app.SHD.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                 change(app);
            end
            
             if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: SHDslider
        function SHDsliderValueChanged(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHD.Value = app.SHDslider.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                  change(app);
            end
            
             if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changing function: SHDslider
        function SHDsliderValueChanging(app, event)
            type = getParachuteType(app);
            if type(1) == 3
                app.iD = app.SHD.Value;
                app.iR = app.SHD.Value / 2;
            else
                app.sD = app.SHD.Value;
                app.sR = app.SHD.Value / 2;
            end
            
            app.SHD.Value = event.Value;
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                change(app);
            end 
            
             if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Value changed function: RocketMassEditField
        function RocketMassEditFieldValueChanged(app, event)
            value = app.RocketMassEditField.Value;
            app.m = value;
            
            if app.Diameter.Value ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value, 'On') 
                app.CDEditField.Visible = 'on';
                app.cD = app.CDEditField.Value;
            else
                app.CDEditField.Visible = 'off';
                parachuteType = getParachuteType(app);
                pt = parachuteType(1,1);
                    switch pt
                        case 1
                            app.cD = .8;
                        case 2
                            app.cD = 1;
                        case 3
                            app.cD = 2;
                    end
            end
            
            if app.m ~= 0 
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
            
        end

        % Value changed function: CDEditField
        function CDEditFieldValueChanged(app, event)
            value = app.CDEditField.Value;
            app.cD = value;
                
            if app.m ~= 0 && app.n ~= 0
               app.DescentVelocityEditField.Value = calcDescVal(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: ParachuteTypeButtonGroup
        function ParachuteTypeButtonGroupSelectionChanged(app, event)
            selectedButton = getParachuteType(app);
            switch selectedButton(1)
                case 1
                    app.cD = selectedButton(2);
                    if app.iR ~= 0
                       app.sR = app.iR; 
                    end
             
                    app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
                    app.SpillHoleDiameterLabel.Position = [7,90,102,22];
                    app.spillholeUnits.Position = [116,90,23,20];
                    
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                        change(app);
                    end
                    
                    if app.m ~= 0 && app.n ~= 0
                        app.DescentVelocityEditField.Value = calcDescVal(app);
                    end
                case 2
                    app.cD = selectedButton(2); 
                    if app.iR ~= 0
                       app.sR = app.iR; 
                    end
                    
                    app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
                    app.SpillHoleDiameterLabel.Position = [7,90,102,22];
                    app.spillholeUnits.Position = [116,90,23,20];
         
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0
                        change(app);
                    end
                    
                    if app.m ~= 0 && app.n ~= 0
                        app.DescentVelocityEditField.Value = calcDescVal(app);
                    end
                case 3                 
                    app.cD = selectedButton(2);
                    app.iR = app.sR;
                    
                    app.SpillHoleDiameterLabel.Text = 'Inner Diameter';
                    app.SpillHoleDiameterLabel.Position = [10,90,79,22];
                    app.spillholeUnits.Position = [101,91,23,20];
                    
                    if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0 
                        change(app);
                    end
                    
                    if app.m ~= 0 && app.n ~= 0
                        app.DescentVelocityEditField.Value = calcDescVal(app);
                    end
            end
            
           
        end

        % Value changed function: SeamWidthDropDown
        function SeamWidthDropDownValueChanged(app, event)
            value = app.SeamWidthDropDown.Value;
            switch value
                case '1/4"'
                    app.seam = 1/4;
                case '3/8"'
                    app.seam = 3/8;
                case '1/2"'
                    app.seam = 1/2;
                case '3/4"'
                    app.seam = 3/4;
                case '1"'
                    app.seam = 1;
            end
            
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0 
                    change(app);
            end
        end

        % Value changed function: DXFFileNameEditField
        function DXFFileNameEditFieldValueChanged(app, event)
            value = app.DXFFileNameEditField.Value;
            
        end

        % Value changed function: ExportButton
        function ExportButtonValueChanged(app, event)
            app.ExportButton.Enable;
            if app.Diameter.Value ~= 0 && app.NOG.Value ~= 0 
                fileName = app.DXFFileNameEditField.Value;
                dxf = '.dxf';
                if contains(fileName, dxf) == 0
                   fileName = strcat(fileName,dxf);
                end
                     
                FID = dxf_open(fileName);
                
                %following top half of gore w/ seam
                x1 = linspace(-app.seam,app.zero,250)';
                y1 = zeros(length(x1),1);
                for i = 1 : length(x1)
                    y1(i) = app.wPs(x1(i));
                end
                
                %vertical line top to bottom near end
                x2 = ones(250,1).*app.zero;
                y2 = linspace(app.wPs(app.zero), app.wNs(app.zero),250)';
                
                %following bottom half (negative portion from tip to base
                x3 = linspace(app.zero, -app.seam, 250)';
                y3 = zeros(length(x3),1);
                for i = 1 : length(x3)
                    y3(i) = app.wNs(x3(i));
                end   
                
                %vertical line near base
                x4 = ones(250,1).*(-app.seam);
                y4 = linspace(app.wNs(-app.seam), app.wPs(-app.seam),250)';
             
                X = cat(1, x1, x2, x3, x4);
                Y = cat(1, y1, y2, y3, y4);
                Z = zeros(length(X));
               
                dxf_polyline(FID,X,Y,Z);
                dxf_close(FID);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2-D Gore')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.DiameterSlider = uislider(app.Panel);
            app.DiameterSlider.Limits = [0 150];
            app.DiameterSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
            app.DiameterSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100', '110', '120', '130', '140', '150'};
            app.DiameterSlider.ValueChangedFcn = createCallbackFcn(app, @DiameterSliderValueChanged, true);
            app.DiameterSlider.ValueChangingFcn = createCallbackFcn(app, @DiameterSliderValueChanging, true);
            app.DiameterSlider.MinorTicks = [];
            app.DiameterEditFieldLabel = uilabel(app.Panel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.Diameter = uieditfield(app.Panel, 'numeric');
            app.Diameter.ValueChangedFcn = createCallbackFcn(app, @DiameterValueChanged, true);
            app.SHDslider = uislider(app.Panel);
            app.SHDslider.Limits = [0 26];
            app.SHDslider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26];
            app.SHDslider.ValueChangedFcn = createCallbackFcn(app, @SHDsliderValueChanged, true);
            app.SHDslider.ValueChangingFcn = createCallbackFcn(app, @SHDsliderValueChanging, true);
            app.SpillHoleDiameterLabel = uilabel(app.Panel);
            app.SpillHoleDiameterLabel.Text = 'Spill Hole Diameter';
            app.SHD = uieditfield(app.Panel, 'numeric');
            app.SHD.ValueChangedFcn = createCallbackFcn(app, @SHDValueChanged, true);
            app.ofGoresSpinnerLabel = uilabel(app.Panel);
            app.ofGoresSpinnerLabel.Text = '# of Gores';
            app.NOG = uispinner(app.Panel);
            app.NOG.ValueChangingFcn = createCallbackFcn(app, @NOGValueChanging, true);
            app.NOG.ValueChangedFcn = createCallbackFcn(app, @NOGValueChanged, true);
            app.diameterUnits = uilabel(app.Panel);
            app.diameterUnits.Text = '(in)';
            app.spillholeUnits = uilabel(app.Panel);
            app.spillholeUnits.Text = '(in)';
            app.SeamWidthDropDownLabel = uilabel(app.Panel);
            app.SeamWidthDropDownLabel.Text = 'Seam Width';
            app.SeamWidthDropDown = uidropdown(app.Panel);
            app.SeamWidthDropDown.Items = {'1/4"', '3/8"', '1/2"', '3/4"', '1"'};
            app.SeamWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @SeamWidthDropDownValueChanged, true);
            app.SeamWidthDropDown.Value = '1/4"';
            app.ParachuteTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ParachuteTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParachuteTypeButtonGroupSelectionChanged, true);
            app.ParachuteTypeButtonGroup.Title = 'Parachute Type';
            app.FlatCircularButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.FlatCircularButton.Text = 'Flat Circular';
            app.FlatCircularButton.Value = true;
            app.HemisphericalButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.HemisphericalButton.Text = 'Hemispherical';
            app.TorridialButton = uiradiobutton(app.ParachuteTypeButtonGroup);
            app.TorridialButton.Text = 'Torridial';
            app.CD10Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD10Label.Text = '*CD = 1.0';
            app.CD20Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD20Label.Text = '*CD = 2.0';
            app.CD08Label = uilabel(app.ParachuteTypeButtonGroup);
            app.CD08Label.Text = '*CD = 0.8';
            app.CDLabel = uilabel(app.ParachuteTypeButtonGroup);
            app.CDLabel.Text = 'CD =';
            app.CDEditField = uieditfield(app.ParachuteTypeButtonGroup, 'numeric');
            app.CDEditField.ValueChangedFcn = createCallbackFcn(app, @CDEditFieldValueChanged, true);
            app.CDEditField.Value = 1;
            app.Switch = uiswitch(app.ParachuteTypeButtonGroup, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.OverrideCDLabel = uilabel(app.ParachuteTypeButtonGroup);
            app.OverrideCDLabel.Text = '*Override CD';
            app.DescentVelocityCalculatorPanel = uipanel(app.UIFigure);
            app.DescentVelocityCalculatorPanel.Title = 'Descent Velocity Calculator';
            app.RocketMassEditFieldLabel = uilabel(app.DescentVelocityCalculatorPanel);
            app.RocketMassEditFieldLabel.Text = 'Rocket Mass';
            app.RocketMassEditField = uieditfield(app.DescentVelocityCalculatorPanel, 'numeric');
            app.RocketMassEditField.ValueChangedFcn = createCallbackFcn(app, @RocketMassEditFieldValueChanged, true);
            app.Lamp = uilamp(app.DescentVelocityCalculatorPanel);
            app.DescentVelocityEditFieldLabel = uilabel(app.DescentVelocityCalculatorPanel);
            app.DescentVelocityEditFieldLabel.Text = 'Descent Velocity ';
            app.DescentVelocityEditField = uieditfield(app.DescentVelocityCalculatorPanel, 'numeric');
            app.MassUnits = uilabel(app.DescentVelocityCalculatorPanel);
            app.MassUnits.Text = '(lb)';
            app.velocityUnits = uilabel(app.DescentVelocityCalculatorPanel);
            app.velocityUnits.Text = '(ft/s)';
            app.ParachuteDescentVelocityandGoreCalculatorLabel = uilabel(app.UIFigure);
            app.ParachuteDescentVelocityandGoreCalculatorLabel.Text = '      Parachute Descent Velocity and Gore Calculator';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3-D Gore')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ExportasDXFfileexportsdashedblueseamlinesPanel = uipanel(app.UIFigure);
            app.ExportasDXFfileexportsdashedblueseamlinesPanel.Title = 'Export as DXF file (exports dashed blue seam lines)';
            app.DXFFileNameEditFieldLabel = uilabel(app.ExportasDXFfileexportsdashedblueseamlinesPanel);
            app.DXFFileNameEditFieldLabel.Text = 'DXF File Name';
            app.DXFFileNameEditField = uieditfield(app.ExportasDXFfileexportsdashedblueseamlinesPanel, 'text');
            app.DXFFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @DXFFileNameEditFieldValueChanged, true);
            app.DXFFileNameEditField.Value = 'MyParachute';
            app.ExportButton = uibutton(app.ExportasDXFfileexportsdashedblueseamlinesPanel, 'state');
            app.ExportButton.ValueChangedFcn = createCallbackFcn(app, @ExportButtonValueChanged, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parachuteApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end